classdef bodeapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BODEAPP                     matlab.ui.Figure
        NumeratoreEditFieldLabel    matlab.ui.control.Label
        NumeratoreEditField         matlab.ui.control.EditField
        DenominatoreEditFieldLabel  matlab.ui.control.Label
        DenominatoreEditField       matlab.ui.control.EditField
        FunzionediTrasferimentoCicloApertoLabel  matlab.ui.control.Label
        DiagrammiButton             matlab.ui.control.Button
        OmegaminEditFieldLabel      matlab.ui.control.Label
        OmegaminEditField           matlab.ui.control.NumericEditField
        OmegamaxEditFieldLabel      matlab.ui.control.Label
        OmegamaxEditField           matlab.ui.control.NumericEditField
        numEditFieldLabel           matlab.ui.control.Label
        numEditField                matlab.ui.control.EditField
        denEditFieldLabel           matlab.ui.control.Label
        denEditField                matlab.ui.control.EditField
        asintoticoCheckBox          matlab.ui.control.CheckBox
        correzionereteCheckBox      matlab.ui.control.CheckBox
        TabGroup                    matlab.ui.container.TabGroup
        BodeModuloTab               matlab.ui.container.Tab
        UIAxes                      matlab.ui.control.UIAxes
        BodeFaseTab                 matlab.ui.container.Tab
        UIAxes2                     matlab.ui.control.UIAxes
        NyquistTab                  matlab.ui.container.Tab
        UIAxes3                     matlab.ui.control.UIAxes
        CicloChiusoTab              matlab.ui.container.Tab
        UIAxes4                     matlab.ui.control.UIAxes
        StepResponceTab             matlab.ui.container.Tab
        UIAxes5                     matlab.ui.control.UIAxes
        SaveDataButton              matlab.ui.control.Button
        LoadDataButton              matlab.ui.control.Button
        Image                       matlab.ui.control.Image
        RetecorrettriceLabel        matlab.ui.control.Label
        ver21Label                  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DiagrammiButton
        function DiagrammiButtonPushed(app, event)
        
            app.UIAxes.reset
            app.UIAxes2.reset
            app.UIAxes3.reset
            app.UIAxes4.reset
            app.UIAxes5.reset
            
            w=logspace(log10(app.OmegaminEditField.Value),log10(app.OmegamaxEditField.Value),1000);
            num=eval(app.NumeratoreEditField.Value);
            den=eval(app.DenominatoreEditField.Value);
            
            Rnum=eval(app.numEditField.Value);
            Rden=eval(app.denEditField.Value);

            F=tf(num,den);
            [m1,f1]=bode(F,w);
            m2=20*log10(squeeze(m1(1,1,:)));
            f2=squeeze(f1(1,1,:));

            Rete=tf(Rnum,Rden);
            [rm1,rf1]=bode(Rete,w);
            rm2=20*log10(squeeze(rm1(1,1,:)));
            rf2=squeeze(rf1(1,1,:))-180;   % per riportarla ai -180 gradi

            F2=F*Rete;
            [m4,f4]=bode(F2,w);
            m5=20*log10(squeeze(m4(1,1,:)));
            f5=squeeze(f4(1,1,:));


            [m,f]=asintotico(num,den,w);
            %semilogx(w,m2)
            
            semilogx(app.UIAxes ,w,m2)
            hold(app.UIAxes,'on')
            
            if app.asintoticoCheckBox.Value==1
                semilogx(app.UIAxes,w,m)
            end
            
            if app.correzionereteCheckBox.Value==1
                semilogx(app.UIAxes,w,rm2)
                semilogx(app.UIAxes,w,m5)               
            end
            
            %title(app.UIAxes,'Modulo')
            xlabel(app.UIAxes,'\omega')
            ylabel(app.UIAxes,'dB')
            grid(app.UIAxes,'on')
            
            semilogx(app.UIAxes2 ,w,f2)
            hold(app.UIAxes2,'on')
            
            if app.asintoticoCheckBox.Value==1
                semilogx(app.UIAxes2,w,f)
            end
            
            if app.correzionereteCheckBox.Value==1
                semilogx(app.UIAxes2,w,rf2)
                semilogx(app.UIAxes2,w,f5) 
            end
                
            %title(app.UIAxes2,'Fase')
            xlabel(app.UIAxes2,'\omega')
            ylabel(app.UIAxes2,'Deg')
            grid(app.UIAxes2,'on')


            [RE,IM] = nyquist(F,w);
            re2=squeeze(RE(1,1,:));
            im2=squeeze(IM(1,1,:));
            
            [RE,IM] = nyquist(F2,w);
            re3=squeeze(RE(1,1,:));
            im3=squeeze(IM(1,1,:));
            
            % Nyquist
            plot(app.UIAxes3,re2,im2)
            hold(app.UIAxes3,'on')
            plot(app.UIAxes3,re2,-im2)
            %title(app.UIAxes3,'Nyquist')
            grid(app.UIAxes3,'on')
            
            if app.correzionereteCheckBox.Value==1
                plot(app.UIAxes3,re3,im3)
                plot(app.UIAxes3,re3,-im3)
            end
            
            
            W=feedback(F,1);
            [m3,f3]=bode(W,w);
            m4=20*log10(squeeze(m3(1,1,:)));
           
            W2=feedback(F2,1);
            [m6,f6]=bode(W2,w);
            m7=20*log10(squeeze(m6(1,1,:)));
           
            
            semilogx(app.UIAxes4 ,w,m4)
            hold(app.UIAxes4,'on')
            
            if app.correzionereteCheckBox.Value==1
                semilogx(app.UIAxes4 ,w,m7)
            end
            
            
            %title(app.UIAxes4,'Ciclo chiuso')
            xlabel(app.UIAxes4,'\omega')
            ylabel(app.UIAxes4,'dB')
            grid(app.UIAxes4,'on')
            
            % Step responce
            
            [y1,t1]=step(W);
            [y2,t2]=step(W2);
            
            plot(app.UIAxes5,t1,y1)
            hold(app.UIAxes5,'on')
            
            if app.correzionereteCheckBox.Value==1
                plot(app.UIAxes5,t2,y2)
            end
            %title(app.UIAxes5,'Step Responce')
            xlabel(app.UIAxes5,'sec')
            ylabel(app.UIAxes5,'y')
            grid(app.UIAxes5,'on')
            
            
        end

        % Button pushed function: SaveDataButton
        function SaveDataButtonPushed(app, event)
            save1=app.NumeratoreEditField.Value;
            save2=app.DenominatoreEditField.Value;
            save3=app.numEditField.Value;
            save4=app.denEditField.Value;
            save5=app.OmegaminEditField.Value;
            save6=app.OmegamaxEditField.Value;
            save('bodeappfile.mat','save*')
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
        try
            load('bodeappfile.mat')
        catch
            return
        end
            app.NumeratoreEditField.Value=save1;
            app.DenominatoreEditField.Value=save2;
            app.numEditField.Value=save3;
            app.denEditField.Value=save4;
            app.OmegaminEditField.Value=save5;
            app.OmegamaxEditField.Value=save6;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BODEAPP = uifigure('Visible', 'off');
            app.BODEAPP.Name = 'Bode App - Stefano Panzieri';
            app.NumeratoreEditFieldLabel = uilabel(app.BODEAPP);
            app.NumeratoreEditFieldLabel.Text = 'Numeratore';
            app.NumeratoreEditField = uieditfield(app.BODEAPP, 'text');
            app.NumeratoreEditField.Value = '[1]';
            app.DenominatoreEditFieldLabel = uilabel(app.BODEAPP);
            app.DenominatoreEditFieldLabel.Text = 'Denominatore';
            app.DenominatoreEditField = uieditfield(app.BODEAPP, 'text');
            app.DenominatoreEditField.Value = '[1 1]';
            app.FunzionediTrasferimentoCicloApertoLabel = uilabel(app.BODEAPP);
            app.FunzionediTrasferimentoCicloApertoLabel.Text = 'Funzione di Trasferimento Ciclo Aperto';
            app.DiagrammiButton = uibutton(app.BODEAPP, 'push');
            app.DiagrammiButton.ButtonPushedFcn = createCallbackFcn(app, @DiagrammiButtonPushed, true);
            app.DiagrammiButton.Text = 'Diagrammi';
            app.OmegaminEditFieldLabel = uilabel(app.BODEAPP);
            app.OmegaminEditFieldLabel.Text = 'Omega min';
            app.OmegaminEditField = uieditfield(app.BODEAPP, 'numeric');
            app.OmegaminEditField.Value = 0.1;
            app.OmegamaxEditFieldLabel = uilabel(app.BODEAPP);
            app.OmegamaxEditFieldLabel.Text = 'Omega max';
            app.OmegamaxEditField = uieditfield(app.BODEAPP, 'numeric');
            app.OmegamaxEditField.Value = 1000;
            app.numEditFieldLabel = uilabel(app.BODEAPP);
            app.numEditFieldLabel.Text = 'num';
            app.numEditField = uieditfield(app.BODEAPP, 'text');
            app.numEditField.Value = '[1]';
            app.denEditFieldLabel = uilabel(app.BODEAPP);
            app.denEditFieldLabel.Text = 'den';
            app.denEditField = uieditfield(app.BODEAPP, 'text');
            app.denEditField.Value = '[1]';
            app.asintoticoCheckBox = uicheckbox(app.BODEAPP);
            app.asintoticoCheckBox.Text = 'asintotico';
            app.correzionereteCheckBox = uicheckbox(app.BODEAPP);
            app.correzionereteCheckBox.Text = 'correzione rete';
            app.TabGroup = uitabgroup(app.BODEAPP);
            app.BodeModuloTab = uitab(app.TabGroup);
            app.BodeModuloTab.Title = 'Bode Modulo';
            app.UIAxes = uiaxes(app.BodeModuloTab);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.BodeFaseTab = uitab(app.TabGroup);
            app.BodeFaseTab.Title = 'Bode Fase';
            app.UIAxes2 = uiaxes(app.BodeFaseTab);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.NyquistTab = uitab(app.TabGroup);
            app.NyquistTab.Title = 'Nyquist';
            app.UIAxes3 = uiaxes(app.NyquistTab);
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.CicloChiusoTab = uitab(app.TabGroup);
            app.CicloChiusoTab.Title = 'Ciclo Chiuso';
            app.UIAxes4 = uiaxes(app.CicloChiusoTab);
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            app.StepResponceTab = uitab(app.TabGroup);
            app.StepResponceTab.Title = 'Step Responce';
            app.UIAxes5 = uiaxes(app.StepResponceTab);
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            app.SaveDataButton = uibutton(app.BODEAPP, 'push');
            app.SaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDataButtonPushed, true);
            app.SaveDataButton.Text = 'Save Data';
            app.LoadDataButton = uibutton(app.BODEAPP, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.Image = uiimage(app.BODEAPP);
            app.Image.ImageSource = 'logo_RM3_20.jpg';
            app.RetecorrettriceLabel = uilabel(app.BODEAPP);
            app.RetecorrettriceLabel.Text = 'Rete correttrice';
            app.ver21Label = uilabel(app.BODEAPP);
            app.ver21Label.Text = 'ver 2.1';
            % Show the figure after all components are created
            app.BODEAPP.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bodeapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BODEAPP)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BODEAPP)
        end
    end
end